classdef GIC_simulation_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        OptionsTab                      matlab.ui.container.Tab
        ExportEFieldButton              matlab.ui.control.Button
        ETypeDropDown                   matlab.ui.control.DropDown
        ETypeDropDownLabel              matlab.ui.control.Label
        eFileTextArea                   matlab.ui.control.TextArea
        LoadEButton                     matlab.ui.control.Button
        FigExportSetingsPanel           matlab.ui.container.Panel
        sizeRatioDropDown               matlab.ui.control.DropDown
        SizeDropDownLabel               matlab.ui.control.Label
        FontEditFieldLabel              matlab.ui.control.Label
        FontEditField                   matlab.ui.control.NumericEditField
        SaveGraphButton                 matlab.ui.control.Button
        BackgroundDropdown              matlab.ui.control.DropDown
        BackgroundDropDownLabel         matlab.ui.control.Label
        ResolutionField                 matlab.ui.control.NumericEditField
        DPIEditFieldLabel               matlab.ui.control.Label
        FormatDropdown                  matlab.ui.control.DropDown
        FormatDropDownLabel             matlab.ui.control.Label
        GraphDropdown                   matlab.ui.control.DropDown
        GraphDropDownLabel              matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        StatusPanel                     matlab.ui.container.Panel
        StatusTextArea                  matlab.ui.control.TextArea
        GICSimulatorLabel               matlab.ui.control.Label
        ActionPanel                     matlab.ui.container.Panel
        StatusLamp_3                    matlab.ui.control.Lamp
        PlotButton                      matlab.ui.control.Button
        GICMapModeDropdown              matlab.ui.control.DropDown
        SelectMapPlotDropDownLabel      matlab.ui.control.Label
        StatusLamp_2                    matlab.ui.control.Lamp
        StatusLamp                      matlab.ui.control.Lamp
        RunGICOverviewButton            matlab.ui.control.Button
        RunGICSimulationButton          matlab.ui.control.Button
        TimePanel                       matlab.ui.container.Panel
        UsesingletimeCheckBox           matlab.ui.control.CheckBox
        EndTimeDropdown                 matlab.ui.control.DropDown
        EndTimeDropDownLabel            matlab.ui.control.Label
        StartTimeDropdown               matlab.ui.control.DropDown
        StartTimeDropDownLabel          matlab.ui.control.Label
        ValidateButton                  matlab.ui.control.Button
        EndDatePicker                   matlab.ui.control.DatePicker
        EndDatePickerLabel              matlab.ui.control.Label
        StartDatePicker                 matlab.ui.control.DatePicker
        StartDatePickerLabel            matlab.ui.control.Label
        ControlPannelPanel              matlab.ui.container.Panel
        LoadNetworkButton               matlab.ui.control.Button
        NetworkFileTextArea             matlab.ui.control.TextArea
        MagneticFileTextArea            matlab.ui.control.TextArea
        SelectMagneticFileButton        matlab.ui.control.Button
        ZTypeDropDown                   matlab.ui.control.DropDown
        ZTypeDropDownLabel              matlab.ui.control.Label
        Hyperlink                       matlab.ui.control.Hyperlink
        UniformityCheckBox              matlab.ui.control.CheckBox
        FrequencyDropDown               matlab.ui.control.DropDown
        FrequencyDropDownLabel          matlab.ui.control.Label
        LoadFilesButton                 matlab.ui.control.Button
        ImpedanceDataPanel              matlab.ui.container.Panel
        SelectSurfaceImpedanceFileButton  matlab.ui.control.Button
        LineImpedanceFileTextArea       matlab.ui.control.TextArea
        SelectLineImpedanceFileButton   matlab.ui.control.Button
        ImpedanceFileTextArea           matlab.ui.control.TextArea
        MagneticVisualsTab              matlab.ui.container.Tab
        SiteListBox                     matlab.ui.control.ListBox
        SiteListBoxLabel                matlab.ui.control.Label
        MapPanel                        matlab.ui.container.Panel
        ControlsPanel                   matlab.ui.container.Panel
        MagneticBPlotsPanel             matlab.ui.container.Panel
        GICMitigationStrategyTab        matlab.ui.container.Tab
        RandomizedmitigationsettingPanel  matlab.ui.container.Panel
        RunRandomMitigationButton       matlab.ui.control.Button
        TargetValueEditField            matlab.ui.control.NumericEditField
        TargetValueEditFieldLabel       matlab.ui.control.Label
        ResulttypeDropDown              matlab.ui.control.DropDown
        ResulttypeDropDownLabel         matlab.ui.control.Label
        MaxMitigationsimEditField       matlab.ui.control.NumericEditField
        MaxMitigationsimEditFieldLabel  matlab.ui.control.Label
        MinMitigationsimEditField       matlab.ui.control.NumericEditField
        MinMitigationsimEditFieldLabel  matlab.ui.control.Label
        NumofSimulationEditField        matlab.ui.control.NumericEditField
        NumofSimulationEditFieldLabel   matlab.ui.control.Label
        AlllinesCheckBox                matlab.ui.control.CheckBox
        NeutralblockersCheckBox         matlab.ui.control.CheckBox
        ParallellinesCheckBox           matlab.ui.control.CheckBox
        HighvoltagelinesCheckBox        matlab.ui.control.CheckBox
        SpreadsheetResultsPanel         matlab.ui.container.Panel
        SetupPanel                      matlab.ui.container.Panel
        PlotLamp                        matlab.ui.control.Lamp
        UpdateLamp                      matlab.ui.control.Lamp
        ExportBtn                       matlab.ui.control.Button
        ClearBtn                        matlab.ui.control.Button
        PlotBtn                         matlab.ui.control.Button
        UpdateBtn                       matlab.ui.control.Button
        AbsThresholdAEdit               matlab.ui.control.NumericEditField
        MinAEditFieldLabel              matlab.ui.control.Label
        ThresholdPctEdit                matlab.ui.control.NumericEditField
        MinchangeEditFieldLabel         matlab.ui.control.Label
        MitigationSettingsPanel         matlab.ui.container.Panel
        MitigationModeDropDown          matlab.ui.control.DropDown
        RunothermassindividualmitigationsCheckBox  matlab.ui.control.CheckBox
        TurnoffallHighvoltagelinesindividuallyCheckBox  matlab.ui.control.CheckBox
        SandboxtopologyeditorTab        matlab.ui.container.Tab
        ResetSubsPositionButton         matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        Gic_subvsEfieldOrientaionTab    matlab.ui.container.Tab
        ShowAllCheckBox                 matlab.ui.control.CheckBox
        SBUIAxes                        matlab.ui.control.UIAxes
        Gic_linevsEFieldOrientationTab  matlab.ui.container.Tab
        SBUIAxes2                       matlab.ui.control.UIAxes
        EmfvsEfieldOrientation          matlab.ui.container.Tab
        SBUIAxes3                       matlab.ui.control.UIAxes
        ToolboxPanel                    matlab.ui.container.Panel
        ShowPreviousResultCheckBox      matlab.ui.control.CheckBox
        SBLamp                          matlab.ui.control.Lamp
        RunSimulationButton             matlab.ui.control.Button
        EfieldValueEditField            matlab.ui.control.NumericEditField
        EfieldValueEditFieldLabel       matlab.ui.control.Label
        NumberofE_fieldpointsEditField  matlab.ui.control.NumericEditField
        ofE_fieldpointsnxnnEditFieldLabel  matlab.ui.control.Label
        TabGroup4                       matlab.ui.container.TabGroup
        L1Tab                           matlab.ui.container.Tab
        L2Tab                           matlab.ui.container.Tab
        L3Tab                           matlab.ui.container.Tab
        L4Tab                           matlab.ui.container.Tab
        L5Tab                           matlab.ui.container.Tab
        Tab                             matlab.ui.container.Tab
        Tab2                            matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        S1Tab                           matlab.ui.container.Tab
        S2Tab                           matlab.ui.container.Tab
        S3Tab                           matlab.ui.container.Tab
        S4Tab                           matlab.ui.container.Tab
        S5Tab                           matlab.ui.container.Tab
        S6Tab                           matlab.ui.container.Tab
        SandboxAxes                     matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = public)
        StoredAxes struct = struct('Name', {}, 'Axes', {})  % List of named axes
    end
        
    properties (Access = public)
        %--------------------
        % Folders & Files
        %--------------------
        MagneticFolder    string
        ImpedanceFolder   string
        NetworkFolder     string
    
        MagneticFile      string
        ImpedanceFile     string
        LineImpedanceFile string
        NetworkFile
        eFile             string
        surfaceEFile      string
        lineEFile         string
    
        %--------------------
        % Calculation variables
        %--------------------
        z_typeValue double
        uniform       logical
        freqmenu = 1
        t1 datetime
        t2 datetime
        b_freq struct
    
        %--------------------
        % Core data objects
        %--------------------
        S            % Substations
        L            % Down sampled Transmission lines
        L_plot       % Original Transmission Lines
        T            % Transformers
        nLines
        nSubs
        nTrans
        subLoc
        SubstationCoords    % Nx2 [lat, lon] for each substation
    
        %--------------------
        % E-field / surface grid
        %--------------------
        Ex_s
        Ey_s
        lat_s
        lon_s
        ex               % E-field X (original)
        ey               % E-field Y (original)
        latq
        lonq
        tind
    
        %--------------------
        % GIC results
        %--------------------
        GIC
        gic_originalS
        gic_originalL
        gic_originalT
        GIC_sandBox
        GIC_sandBox_Old
    
        %--------------------
        % B-field editing
        %--------------------
        Bx
        By
        b_cleaned
        b_original
        EditLamp
        siteIndices
    
        %--------------------
        % Network editing & tables
        %--------------------
        LineTable
        LineTableData
        TransTable
        OriginalL
        OriginalT
        LineEnabled
        TransEnabled
        LinePlots
        TransPlots
        NetworkStatus
        NetworkMapAxes
        NetworkMapAxes_E
    
        %--------------------
        % Mitigation
        %--------------------
        MitigationResults
        SpreadsheetTable
        GreedyMitigation
    
        %--------------------
        % Plotting / UI handles
        %--------------------
        instanTime
        GICVectorHandles       % Array to store quiver arrows
        shaftHandles           % E-field line handles
        arrowHandles
        SandboxNodeHandles
        SandboxLineHandles
        SandboxTextHandles
        SandboxEfieldPointsHandle
    
        %--------------------
        % Sandbox / editing state
        %--------------------
        SandboxS
        SandboxL
        SandboxT
        OriginalSandboxL
        OldSandboxL
        SandboxTopology
        SelectedNode
        IsDragging
        SelectedLine
    
        %--------------------
        % Sandbox drawing params
        %--------------------
        SandboxAngle = 180
        SandboxAngleStep = 2
        theta
        SandboxScaleKm = 1
        SandboxXMin = -150
        SandboxXMax = 150
        SandboxYMin = -150
        SandboxYMax = 150
        sandBoxMode logical
        SandboxProj
        SandboxOriginEasting  = 500000    % Center of Alberta
        SandboxOriginNorthing = 6040000
        SandboxCentralLongitude = -115    % UTM Zone 12
        SandboxOriginLatitude   = 54.5678
    
        %--------------------
        % Substation controls
        %--------------------
        SubGroundEdit
        SubTypeDropDown
        SubWindingEdit
    
        %--------------------
        % Line controls
        %--------------------
        LineConnectedCheck
        LineVoltageDropDown
        LineCircuitDropDown
        LineR1Edit
        LineR2Edit
        LineR3Edit
        LineLengthLabel
        LineBearingLabel
    
        %--------------------
        % Misc flags / status
        %--------------------
        IsCleanMode logical = false
    end

    
    methods (Static, Access = private)    
        function out = ternary(cond, valTrue, valFalse)
            if cond
                out = valTrue;
            else
                out = valFalse;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                %-----------------------------
                % Clear file paths / defaults
                %-----------------------------
                app.MagneticFile  = "";
                app.ImpedanceFile = "";
                app.NetworkFile   = "";
            
                %-----------------------------
                % Time selection dropdowns
                %-----------------------------
                timeLabels = strings(96,1);
                idx = 1;
                for i = 0:0.25:23.75
                    h = floor(i);
                    m = round(mod(i,1)*60);
                    timeLabels(idx) = sprintf('%02d:%02d:00', h, m);
                    idx = idx + 1;
                end
                app.StartTimeDropdown.Items = cellstr(timeLabels);
                app.StartTimeDropdown.Value = '00:00:00';
                app.EndTimeDropdown.Items = cellstr(timeLabels);
                app.EndTimeDropdown.Value = '23:30:00';
            
                %-----------------------------
                % Z-type dropdown
                %-----------------------------
                app.ZTypeDropDown.Items = { ...
                    '3D MT Impedance', ...
                    'Uniform Halfspace', ...
                    '1D Trichtchenko', ...
                    'Data Space Method'};
                app.ZTypeDropDown.ItemsData = [1, 2, 3, 4];
                app.ZTypeDropDown.Value = app.ZTypeDropDown.ItemsData(1);
            
                %-----------------------------
                % Disable processing buttons until files selected
                %-----------------------------
                app.CalculateButton.Enable         = 'off';
                app.RunGICSimulationButton.Enable  = 'off';
                app.RunGICOverviewButton.Enable    = 'off';
                app.ValidateButton.Enable          = 'off';
                app.UsesingletimeCheckBox.Enable   = 'off';
                app.ExportEFieldButton.Enable      = 'off';
            
                %-----------------------------
                % Figure export settings
                %-----------------------------
                app.FormatDropdown.Items = {'png','jpg','tif','pdf','eps','fig'};
                app.FormatDropdown.Value = 'png';
                app.ResolutionField.Value = 300;
                app.BackgroundDropdown.Items = {'white','black','none'};
                app.BackgroundDropdown.Value = 'white';
                app.FontEditField.Value = 37;
                app.sizeRatioDropDown.Items = {'1:1','3:2','4:3','5:4','16:9','9:16'};
                app.sizeRatioDropDown.Value = '1:1';
            
                if ~isempty(app.StoredAxes)
                    app.GraphDropdown.Items = {app.StoredAxes.Name};
                end
            
                %-----------------------------
                % Panel controls
                %-----------------------------
                app.ClearBtn.Enable  = 'off';
                app.ExportBtn.Enable = 'off';
            
                %-----------------------------
                % Mitigation results table (spreadsheet)
                %-----------------------------
                app.MitigationResults = table( ...
                    strings(0,1), ... % Mitigation
                    zeros(0,1),   ... % MaxTransGIC
                    strings(0,1), ... % MaxTransName
                    zeros(0,1),   ... % SumGICSubs
                    zeros(0,1),   ... % MaxLinesGIC
                    strings(0,1), ... % MaxLineName
                    'VariableNames', ...
                    {'Mitigation','MaxTransGIC','MaxTransName','SumGICSubs','MaxLinesGIC','MaxLineName'});
            
                pad = 20;
                app.SpreadsheetTable = uitable(app.SpreadsheetResultsPanel, ...
                    'ColumnSortable', true, ...
                    'FontSize', 12, ...
                    'Data', app.MitigationResults);
                app.SpreadsheetTable.ColumnName = app.MitigationResults.Properties.VariableNames;
                app.SpreadsheetTable.RowStriping = 'on';
                app.SpreadsheetTable.Position = [pad, 5, ...
                    app.SpreadsheetResultsPanel.Position(3)-2*pad, ...
                    app.SpreadsheetResultsPanel.Position(4)-2*pad];
            
                %-----------------------------
                % Initial status message
                %-----------------------------
                app.StatusTextArea.Value = [app.StatusTextArea.Value; "✅ Startup complete. Use buttons to select your files."];
            
                %-----------------------------
                % Sandbox / GIC stored state
                %-----------------------------
                app.GIC_sandBox_Old = struct( ...
                    'Subs',[], ...
                    'Lines',[], ...
                    'iInduced',[]);
            
                %-----------------------------
                % Initialize sandbox editor
                %-----------------------------
                initializeSandboxData(app);
                buildSubstationControls(app);
                buildLineControls(app);
                drawSandboxNetwork(app);
            
                app.SelectedNode = [];
                app.IsDragging = false;
                fig = ancestor(app.SandboxAxes,'figure');
                fig.WindowButtonMotionFcn = @(src,event) sandboxMouseMove(app);
                fig.WindowButtonUpFcn     = @(src,event) sandboxMouseUp(app);
            
                % Expose app to the base workspace for interactive use
                assignin('base','app',app);

                app.sandBoxMode = 0;
                app.ShowAllCheckBox.Enable = 'off';
            
            catch ME
                % Minimal error propagation for startup issues
                warning('Startup:Failed','Startup encountered an error: %s', ME.message);
                rethrow(ME);
            end

        end

        % Button pushed function: SelectMagneticFileButton
        function SelectMagneticFileButtonPushed(app, event)
            app.MagneticFile = selectSimFile(app, 'Magnetic Data', {'*.mat', '*.zip'});
            [~, name, ext] = fileparts(app.MagneticFile);
            app.MagneticFileTextArea.Value = [name ext];
        end

        % Button pushed function: SelectSurfaceImpedanceFileButton
        function SelectSurfaceImpedanceFileButtonPushed(app, event)
            app.ImpedanceFile = selectSimFile(app, 'Impedance Data', {'*.mat'});
            [~, name, ext] = fileparts(app.ImpedanceFile);
            app.ImpedanceFileTextArea.Value = [name ext];
        end

        % Button pushed function: SelectLineImpedanceFileButton
        function SelectImpedanceFileButtonPushed(app, event)
            app.LineImpedanceFile = selectSimFile(app, 'Line Impedance Data', {'*.mat'});
            [~, name, ext] = fileparts(app.LineImpedanceFile);
            app.LineImpedanceFileTextArea.Value = [name ext];
        end

        % Button pushed function: LoadNetworkButton
        function LoadNetworkButtonPushed(app, event)
            try
                % --- Line File ---
                app.NetworkFile = selectSimFile(app, 'Network Data', {'*.mat'});
                [~, name, ext] = fileparts(app.NetworkFile);
                app.NetworkFileTextArea.Value = [name ext];
            catch ME
                app.StatusTextArea.Value = "❌ Error loading network file: " + ME.message;
            end
            
        end

        % Callback function
        function SelectSubFileButtonPushed(app, event)
            app.SubFile = selectSimFile(app, 'Substation Data', {'*.mat'});
            [~, name, ext] = fileparts(app.SubFile);  % Extract just the file name
            app.SubFileTextArea.Value = [name ext];
        end

        % Callback function
        function SelectTransFileButtonPushed(app, event)
            app.TransFile = selectSimFile(app, 'Transformer Data', {'*.mat'});
            [~, name, ext] = fileparts(app.TransFile);
            app.TransFileTextArea.Value = [name ext];
        end

        % Button pushed function: LoadFilesButton
        function LoadFilesButtonPushed(app, event)
            try
                % === Load Magnetic Data ===
                if endsWith(app.MagneticFile, '.mat', 'IgnoreCase', true)
                    % Load from .mat file and extract variable 'b'
                    data = load(app.MagneticFile);
                    if isfield(data, 'b')
                        b = data.b;
                    else
                        error("MAT file does not contain variable 'b'.");
                    end
            
                elseif endsWith(string(app.MagneticFile), ".zip", "IgnoreCase", true)
                    unzipDir = fullfile(tempdir, "unzipped_mag_data");
                    if exist(unzipDir, "dir"), rmdir(unzipDir, "s"); end
                    mkdir(unzipDir);
                
                    files = unzip(string(app.MagneticFile), unzipDir);  % returns list of extracted file paths
                
                    % Pick the content folder (handle nested top-level folder inside the zip)
                    matHits = dir(fullfile(unzipDir, "**", "*.mat"));
                    if ~isempty(matHits)
                        contentDir = matHits(1).folder;        % folder that actually has the .mat files
                    else
                        anyFiles = dir(fullfile(unzipDir, "**", "*.*"));
                        anyFiles = anyFiles(~[anyFiles.isdir]);
                        if isempty(anyFiles)
                            error("ZIP extracted but no files were found.");
                        end
                        contentDir = anyFiles(1).folder;       % fall back to first folder with files
                    end
                
                    % Now pass the **folder path** (not a struct) to your loader
                    b = load_mag_data(contentDir);
                
                    % Clean up if you want
                    % rmdir(unzipDir, "s");
                    
                elseif strcmp(app.MagneticFile, '')
                    app.StatusTextArea.Value= [app.StatusTextArea.Value; "No Magnetic file loaded in"];
                else
                    error("Unsupported file type selected. Only .mat or .zip are accepted.");
                end

                % === Load Network Files ===
                app.StatusTextArea.Value = [app.StatusTextArea.Value; "Loading network files..."];
                drawnow;

                [L, S, T, app.L_plot] = get_network(app.NetworkFile);
                createNetworkEditorTab(app);
                populateNetworkEditor(app, L, T, S);
                
                % === Empty app.GIC ===
                if isprop(app, 'GIC')
                    app.GIC.Original_Subs = [];
                    app.GIC.Original_Lines = [];
                    app.GIC.Original_Trans = [];
                    app.GIC.Subs = [];
                    app.GIC.Lines = [];
                    app.GIC.Trans = [];
                end
                app.StatusTextArea.Value = [app.StatusTextArea.Value; ...
                    'Initialization complete...Check "Network Editor" tab to edit network'];

                % === Set Z Type ===
                z_type = app.ZTypeDropDown.Value;  % Selected impedance processing option

                % === Configure Date Pickers ===
                timeRange = b(1).times;
                app.StartDatePicker.Limits = [timeRange(1), timeRange(end)];
                app.EndDatePicker.Limits   = [timeRange(1), timeRange(end)];
                app.StartDatePicker.Value  = timeRange(1);
                app.EndDatePicker.Value    = min(timeRange(1) + hours(3), timeRange(end));
                
                % === Compute Frequency Domain ===
                app.StatusTextArea.Value = [app.StatusTextArea.Value; "Files loaded successfully, b in time domain obtained."];
                drawnow;
                app.b_freq = conv_to_freqD(b, app.freqmenu);
                app.StatusTextArea.Value = [app.StatusTextArea.Value; "b in frequency domain successfully obtained."];

            
                % === Initialize Site List ===
                app.b_cleaned = b;
                siteNames = string({b.site});
                app.SiteListBox.Items = siteNames;
                app.SiteListBox.Multiselect = true;
            
                % Preselect a few default sites
                defaultSites = siteNames(1:min(3, end));
                app.SiteListBox.Value = defaultSites;
            
                % === Plot Initial Magnetic Field Time & Frequency Graphs ===
                app.siteIndices = [];
                [~, app.siteIndices] = ismember(defaultSites, siteNames);
                drawnow; 
     
                magneticVisualTab(app, app.MagneticBPlotsPanel, app.ControlsPanel, app.MapPanel);
                app.StatusTextArea.Value = [app.StatusTextArea.Value; ...
                    'Check the "Magnetic Visuals" tab for the magnetic field plots that have been generated.'];
            
            
                % === Push Core Variables to Base Workspace (for debugging/advanced use) ===
                assignin('base', 'b', app.b_cleaned);
                assignin('base', 'L', app.L);
                assignin('base', 'S', app.S);
                assignin('base', 'T', app.T);
                assignin('base', 'uniform', app.uniform);
                assignin('base', 'z_type', z_type);
                
                app.CalculateButton.Enable = 'on'; %Continue to E calculation
            catch ME
                % Display error in UI status and rethrow for command line/debugging
                app.StatusTextArea.Value = sprintf("❌ Error: %s", ME.message);
                rethrow(ME);
            end
        end

        % Callback function
        function GetZtypeDropDownValueChanged(app, event)
            app.z_typeValue = app.GetZtypeDropDown.Value; 
        end

        % Value changed function: FrequencyDropDown
        function FrequencyDropDownValueChanged(app, event)
            app.freqmenu = app.FrequencyDropDown.Value;
        end

        % Button pushed function: ValidateButton
        function ValidateButtonPushed(app, event)
            try
                % 1. Extract date and time from UI
                startDate = app.StartDatePicker.Value;
                endDate = app.EndDatePicker.Value;
                startTimeStr = app.StartTimeDropdown.Value;
                endTimeStr = app.EndTimeDropdown.Value;
        
                % 2. Convert time strings to durations
                startTimeDur = duration(startTimeStr, 'InputFormat', 'hh:mm:ss');
                endTimeDur = duration(endTimeStr, 'InputFormat', 'hh:mm:ss');
        
                % 3. Combine date + time
                t1 = datetime(year(startDate), month(startDate), day(startDate)) + startTimeDur;
                t2 = datetime(year(endDate), month(endDate), day(endDate)) + endTimeDur;
        
                % 4. Validate
                if t2 <= t1
                    uialert(app.UIFigure, "End time must be after start time.", "Invalid Time Range");
                    return;
                end
        
                % 5. Store or assign t1/t2 (optional)
                app.t1 = t1;
                app.t2 = t2;
                
                %get time index range
                app.tind = find(isbetween(app.b_cleaned(1).times, t1, t2));

                %Restart gic data
                app.gic_originalS = [];
                app.gic_originalL = [];
                app.gic_originalT = [];
                
                % 7. Show success in StatusTextArea
                app.StatusTextArea.Value = [
                    app.StatusTextArea.Value;
                    "✔ Time range valid:";
                    sprintf("From %s to %s", datestr(t1), datestr(t2))
                ];
                app.RunGICSimulationButton.Enable = 'on'; %Switch on GIC Simulation button
            catch ME
                uialert(app.UIFigure, "Error validating time range: " + ME.message, "Validation Error");
            end

        end

        % Value changed function: UniformityCheckBox
        function UniformityCheckBoxValueChanged(app, event)
            app.uniform = app.UniformityCheckBox.Value;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
            try
                app.Ex_s = [];
                app.Ey_s = [];
                app.ex   = [];
                app.ey   = [];
                
                z_type = app.ZTypeDropDown.Value;
                app.StatusTextArea.Value = [app.StatusTextArea.Value; "Obaining E-Field..."];
                drawnow;


                %% === Surface E ===
                if ~isempty(app.surfaceEFile) && exist(app.surfaceEFile, 'file') == 2
                    s = load(app.surfaceEFile);
                    assert(all(isfield(s, {'Ex','Ey','lat','lon'})), 'Surface E file missing fields');
                    app.Ex_s = s.Ex; app.Ey_s = s.Ey; app.lat_s = s.lat; app.lon_s = s.lon;
                    app.StatusTextArea.Value = [app.StatusTextArea.Value; "✔️ Surface E loaded."];
                else
                    if ~isempty(app.ImpedanceFile) && exist(app.ImpedanceFile, 'file') == 2
                        app.StatusTextArea.Value = "⚙️ Calculating Surface E...";
                        drawnow;
                        [app.Ex_s, app.Ey_s, app.lat_s, app.lon_s, ~, ~] = initialize_gic_simulation(z_type, app.ImpedanceFile, app.b_freq, app);
                    else
                       app.StatusTextArea.Value = [app.StatusTextArea.Value; "No Surface E, line E animation will be plotted instead..."];
                       drawnow;
                    end
                end

                %% === Line E ===
                if ~isempty(app.lineEFile) && exist(app.lineEFile, 'file') == 2
                    s = load(app.lineEFile);
                    assert(all(isfield(s, {'Ex','Ey','lat','lon','Bx', 'By'})), 'Line E file missing fields');
                    app.ex = s.Ex; app.ey = s.Ey;
                    app.latq = s.lat; app.lonq = s.lon;
                    app.Bx = s.Bx; app.By = s.By; 
                    app.StatusTextArea.Value = [app.StatusTextArea.Value; "✔️ Line E loaded."];
                    
                else
                    app.StatusTextArea.Value = [app.StatusTextArea.Value; "⚙️ Calculating Line E..."];
                    drawnow;
                    [app.ex, app.ey, app.latq, app.lonq, app.Bx, app.By] = ...
                        initialize_gic_simulation(z_type, app.LineImpedanceFile, app.b_freq, app);

                end
        
                %% === Plot Surface E Animation ===
                app.StatusTextArea.Value = [app.StatusTextArea.Value; "E obtained. Creating E-field Animation..."];
                drawnow;
                
                if isempty(app.Ex_s) || isempty(app.Ey_s) || isempty(app.lat_s) || isempty(app.lon_s) %if surface E is not available
                    app.Ex_s = app.ex;
                    app.Ey_s = app.ey;
                    app.lat_s = app.latq;
                    app.lon_s = app.lonq;
                end
                create_e_field_animation_tab(app, app.b_freq, app.Ex_s, app.Ey_s, app.lat_s, app.lon_s, app.L, app.S);

                app.StatusTextArea.Value = [app.StatusTextArea.Value; "✅ E Animation Plotted"];
                app.StatusTextArea.Value = [app.StatusTextArea.Value; "...check E field tab to set time on scroller"];
                app.ValidateButton.Enable = 'on';
                app.UsesingletimeCheckBox.Enable = 'on';
                app.ExportEFieldButton.Enable = 'on';
        
            catch ME
                app.StatusTextArea.Value = sprintf("❌ Initialization failed:\n%s", ME.message);
            end
        end

        % Button pushed function: RunGICOverviewButton
        function RunGICOverviewButtonPushed(app, event)
             try  
                app.StatusLamp_3.Color = [1, 1, 1]; % white
                create_gic_overview_tab(app, app.S, app.L, app.T, app.b_cleaned, app.GIC, app.tind, app.instanTime);
                app.StatusLamp_3.Color = [0, 1, 0]; % Green
                app.StatusTextArea.Value = [app.StatusTextArea.Value; "✅ GIC Overview successfully."];
            catch ME
                app.StatusLamp_3.Color = [1, 0, 0]; % Red
                app.StatusTextArea.Value = sprintf("File load failed:\n%s", ME.message);
            end
            
        end

        % Size changed function: MagneticVisualsTab
        function MagneticVisualsTabSizeChanged(app, event)
          
        end

        % Callback function
        function EndTimeDropDownValueChanged(app, event)
            value = app.EndTimeDropdown.Value;
            
        end

        % Callback function
        function StartTimeDropdownDropDownValueChanged(app, event)
            value = app.StartTimeDropdown.Value;
            
        end

        % Value changed function: ZTypeDropDown
        function ZTypeDropDownValueChanged(app, event)

            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            try  
                app.StatusLamp_2.Color = [1, 1, 1]; % white
                mode = app.GICMapModeDropdown.Value;  % dropdown value
                app.StatusTextArea.Value = "Plotting.....";
                plot_gic_mag_map(app, app.S, app.L, app.tind, app.b_cleaned, app.GIC, app.instanTime, mode);
                app.StatusLamp_2.Color = [0, 1, 0]; % Green                 

                app.StatusTextArea.Value = [app.StatusTextArea.Value; "✅ GIC Maps plotted successfully."];
            catch ME
                app.StatusLamp_2.Color = [1, 0, 0]; % Red
                app.StatusTextArea.Value = sprintf("File load failed:\n%s", ME.message);
            end
        end

        % Button pushed function: RunGICSimulationButton
        function RunGICSimulationButtonPushed(app, event)
            try  
                app.StatusLamp.Color = [1, 1, 1]; % white
                [app.S,app.L,app.T, app.GIC, app.nLines, app.nSubs, app.nTrans] = calc_gic_main(app, app.S,app.L,app.T,app.ex,app.ey,app.latq,app.lonq,app.tind,...
                    app.OriginalL, app.OriginalT);

                app.PlotButton.Enable= 'on';
                app.RunGICOverviewButton.Enable='on';
                app.GICMapModeDropdown.Enable='on';
                app.StatusLamp.Color = [0, 1, 0]; % Green
                app.StatusLamp_3.Color = [1, 1, 1]; % white for gic overview lamp
            catch ME
                app.StatusLamp.Color = [1, 0, 0]; % Red
                app.StatusTextArea.Value = sprintf("File load failed:\n%s", ME.message);
            end
        end

        % Value changed function: SiteListBox
        function SiteListBoxValueChanged(app, event)
            % === Switch site ===
            selectedNames = app.SiteListBox.Value;
            [~, app.siteIndices] = ismember(selectedNames, string({app.b_freq.site}));
        
            if isempty(app.siteIndices) || all(app.siteIndices == 0)
                return;
            end
            replot_mag_time_and_freq(app, app.siteIndices, app.MagneticBPlotsPanel); 
            replot_mag_map(app, app.siteIndices, app.MapPanel); 
        end

        % Callback function
        function LoadCachedLoadCachedLoadCatchedButtonPushed(app, event)
            try
                s = load('cached_sim.mat');
                app.Bx = s.Bx;
                app.By = s.By;
                app.T = s.T;
                app.S = s.S;
                app.subLoc = s.subLoc;
                app.GIC_Subs = s.GIC_Subs;
                app.b_freq= s.b_freq;
                app. L= s.L;
                app.tind = s.tind;
                
                create_gic_map_and_timeseries_tab(app, app.S, app.L, app.b_freq, app.GIC_Subs, app.tind);
                uialert(app.UIFigure, "Cached data loaded successfully!", "Success");
                
                app.PlotButton.Enable= 'on';
            catch ME
                app.StatusTextArea.Value = sprintf("File load failed:\n%s", ME.message);
            end
        end

        % Value changed function: GraphDropdown
        function GraphDropdownValueChanged(app, event)
            app.SaveGraphButton.Enable = ~isempty(app.GraphDropdown.Value);
            
        end

        % Button pushed function: SaveGraphButton
        function SaveGraphButtonPushed(app, event)
            % === GET USER SETTINGS ===
            graphName = app.GraphDropdown.Value;
            format = app.FormatDropdown.Value;
            dpi = app.ResolutionField.Value;
            bg = app.BackgroundDropdown.Value;
            font = app.FontEditField.Value;
            ratioStr = app.sizeRatioDropDown.Value;
            
            % === VALIDATE SETTINGS ===
            if isempty(graphName) || isnan(dpi) || dpi <= 0
                uialert(app.UIFigure, 'Please select a graph and enter a valid DPI.', 'Invalid Settings');
                return;
            end
            
            % === FIND MATCHING AXES HANDLE ===
            idx = find(strcmp({app.StoredAxes.Name}, graphName), 1);
            if isempty(idx)
                uialert(app.UIFigure, 'Selected graph not found.', 'Error');
                return;
            end
            ax = app.StoredAxes(idx).Axes;
            
            % === SUGGEST DEFAULT FILENAME BASED ON GRAPH NAME ===
            cleanName = regexprep(graphName, '[^\w\d ]', '_');
            defaultName = [cleanName, '.', format];
            [file, path] = uiputfile(['*.', format], 'Save Graph As', defaultName);
            if isequal(file, 0)
                return;
            end
            filename = fullfile(path, file);
            
            % === DETERMINE CONTENT TYPE ===
            if ismember(lower(format), ["pdf", "eps"])
                contentType = 'vector';
            else
                contentType = 'image';
            end
            
            % === PARSE ASPECT RATIO ===
            tokens = sscanf(ratioStr, '%f:%f');
            if numel(tokens) ~= 2 || any(tokens <= 0)
                uialert(app.UIFigure, 'Invalid size ratio format. Use values like 1:1, 3:4, 16:9.', 'Ratio Error');
                return;
            end
            wRatio = tokens(1);
            hRatio = tokens(2);
            
            % === CHOOSE EXPORT SIZE ===
            baseWidth = 1200;
            exportWidth = baseWidth;
            exportHeight = round(baseWidth * hRatio / wRatio);
            
            % === SET BACKGROUND COLOR ===
            if strcmpi(bg, 'none')
                bgColor = 'none';
            else
                bgColor = bg;
            end
            
            tmpFig = [];
            try
                % === CREATE TEMP FIGURE ===
                tmpFig = figure('Visible', 'off', ...
                    'Units', 'pixels', ...
                    'Position', [100 100 exportWidth exportHeight]);
            
                % === COPY AXES INTO TEMP FIGURE ===
                newAx = copyobj(ax, tmpFig);
                set(newAx, 'Units', 'normalized', 'Position', [0.13 0.11 0.775 0.815]);
            
                % === APPLY EXPORT FONT SETTINGS TO COPIED AXES ONLY ===
                newAx.FontSize = font;
                newAx.Title.FontSize = font;
                newAx.XLabel.FontSize = font;
                newAx.YLabel.FontSize = font;
                
                % ==================== SAVE MATLAB FIGURE FILE ====================
                % .fig files preserve figure properties, including visibility.
                % Therefore, make the temporary figure visible before saving.
                
                if strcmpi(format, 'fig')
                
                    % -------------------- FORCE .FIG EXTENSION --------------------
                    % Make sure the saved file always ends in .fig
                    [folderPath, baseName, ~] = fileparts(filename);
                    filename = fullfile(folderPath, [baseName, '.fig']);
                
                    % -------------------- MAKE FIGURE VISIBLE BEFORE SAVING --------------------
                    % Otherwise the saved .fig may reopen as an invisible figure
                    tmpFig.Visible = 'on';
                
                    % -------------------- SAVE EDITABLE MATLAB FIGURE --------------------
                    % savefig stores the MATLAB figure object
                    savefig(tmpFig, filename);
                
                else
                
                    % -------------------- EXPORT IMAGE OR VECTOR FILE --------------------
                    % These options apply only to png, jpg, tif, pdf, eps, etc.
                    exportgraphics(tmpFig, filename, ...
                        'Resolution', dpi, ...
                        'ContentType', contentType, ...
                        'BackgroundColor', bgColor);
                
                end
                delete(tmpFig);
                %Restore original style
                uialert(app.UIFigure, 'Graph exported successfully.', 'Success');
            catch err
                if ~isempty(tmpFig) && isvalid(tmpFig)
                    delete(tmpFig);
                end
                uialert(app.UIFigure, ['Error exporting graph: ', err.message], 'Export Failed');
            end
        end

        % Button pushed function: LoadEButton
        function LoadEButtonPushed(app, event)
            selectedFile = selectSimFile(app, 'E Data', {'*.mat'});
            if isempty(selectedFile), return; end
        
            [~, name, ext] = fileparts(selectedFile);
            fileDisplayName = [name ext];
        
            switch app.ETypeDropDown.Value
                case 'Surface'
                    app.surfaceEFile = selectedFile;
                    app.eFileTextArea.Value = fileDisplayName; 
                case 'Line'
                    app.lineEFile = selectedFile;
                    app.eFileTextArea.Value = fileDisplayName;
                    app.CalculateButton.Enable = 'on'; %Continue to E calculation
            end
            appendStatus(app, sprintf("✔️ Loaded %s E-File: %s", app.ETypeDropDown.Value, fileDisplayName));

        end

        % Value changed function: UsesingletimeCheckBox
        function UsesingletimeCheckBoxValueChanged(app, event)
            % Value changed function for the checkbox
            if app.UsesingletimeCheckBox.Value
                % Disable time window selection options
                app.StartDatePicker.Enable = 'off';
                app.EndDatePicker.Enable = 'off';
                app.StartTimeDropdown.Enable = 'off';
                app.EndTimeDropdown.Enable = 'off';

                % Enable the run GIC simulation button
                app.RunGICSimulationButton.Enable = 'on';
                

                %get time index range
                app.tind = app.instanTime;

                % 6. Show success in StatusTextArea
                app.StatusTextArea.Value = [
                    app.StatusTextArea.Value;
                    "✔ Time selection valid:";
                    sprintf('Time at: %s', datestr(app.b_cleaned(1).times(app.tind), 'yyyy-mmm-dd HH:MM:SS'));
                ];
            else
                % Enable time window selection options if checkbox is unchecked
                app.StartDatePicker.Enable = 'on';
                app.EndDatePicker.Enable = 'on';
                app.StartTimeDropdown.Enable = 'on';
                app.EndTimeDropdown.Enable = 'on';
                appendStatus(app, 'select time window...');
                
                % Disable the run GIC simulation button
                app.RunGICSimulationButton.Enable = 'off';
            end
                        
        end

        % Button pushed function: UpdateBtn
        function UpdateBtnButtonPushed(app, event)
            UpdateSpreadsheet(app);
        end

        % Button pushed function: PlotBtn
        function PlotBtnButtonPushed(app, event)
            PlotGraphButtonPushed(app);
        end

        % Button pushed function: ExportEFieldButton
        function ExportEFieldButtonPushed(app, event)
            if ~isempty(app.t1) && ~isempty(app.t2)
                t1str = char(app.t1, 'yyyy-MM-dd_HH-mm');
                t2str = char(app.t2, 'yyyy-MM-dd_HH-mm');
            else
                timeRange = app.b_cleaned(1).times;
                t1str = char(timeRange(1), 'yyyy-MM-dd_HH-mm');
                t2str = char(timeRange(end), 'yyyy-MM-dd_HH-mm');
            end

            baseName = sprintf('Line E-Field from %s to %s', t1str, t2str);
            [filename, pathname] = uiputfile('*.mat', 'Save MAT-file', baseName);
            if isequal(filename,0) || isequal(pathname,0)
                app.StatusTextArea.Value = [app.StatusTextArea.Value; "User canceled the operation."];
            else
                data.ex   = app.ex;
                data.ey   = app.ey;
                data.latq = app.latq;
                data.lonq = app.lonq;
                data.tind = app.tind;
                data.b = app.b_cleaned;
            
                save(fullfile(pathname, filename), 'data');
            end
        end

        % Button pushed function: RunRandomMitigationButton
        function RunRandomMitigationButtonPushed(app, event)
             %% -------- READ VALUES --------
            useHighVoltageLines = app.HighvoltagelinesCheckBox.Value;
            useParallelLines    = app.ParallellinesCheckBox.Value;
            useNeutralBlockers  = app.NeutralblockersCheckBox.Value;
            useAllLines         = app.AlllinesCheckBox.Value;
        
            nSim   = app.NumofSimulationEditField.Value;
            minMit = app.MinMitigationsimEditField.Value;
            maxMit = app.MaxMitigationsimEditField.Value;
        
            resultType = app.ResulttypeDropDown.Value;
            target     = app.TargetValueEditField.Value;
        
        
            %% -------- BASIC VALIDATION --------
            if ~(useHighVoltageLines || useParallelLines || ...
                 useNeutralBlockers || useAllLines)
                uialert(app.UIFigure,'Select at least one mitigation type','Error');
                return;
            end
        
            if nSim < 1 || mod(nSim,1) ~= 0
                uialert(app.UIFigure,'Num of Simulation must be a positive integer','Error');
                return;
            end
        
            if minMit < 0 || maxMit < 0 || minMit > maxMit
                uialert(app.UIFigure,'Invalid min/max mitigation values','Error');
                return;
            end
        
            if target < 0
                uialert(app.UIFigure,'Target value must be non-negative','Error');
                return;
            end
        
        
            %% -------- CALL EXTERNAL FUNCTION --------
            run_random_mitigation( ...
                app, ...
                useHighVoltageLines, ...
                useParallelLines, ...
                useNeutralBlockers, ...
                useAllLines, ...
                nSim, ...
                minMit, ...
                maxMit, ...
                resultType, ...
                target ...
            );
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
        try
        
            app.SBLamp.Color = [0.90,0.90,0.90];
        
            % =====================================================
            % E-Field Visualization Grid
            % =====================================================
            nGrid = app.NumberofE_fieldpointsEditField.Value;
        
            x = linspace( ...
                app.SandboxXMin,...
                app.SandboxXMax,...
                nGrid);
        
            y = linspace( ...
                app.SandboxYMin,...
                app.SandboxYMax,...
                nGrid);
        
            [X,Y] = meshgrid(x,y);
        
            if isempty(app.SandboxEfieldPointsHandle) || ...
                    ~isvalid(app.SandboxEfieldPointsHandle)
        
                app.SandboxEfieldPointsHandle = ...
                    scatter(app.SandboxAxes,...
                    X(:),...
                    Y(:),...
                    10,...
                    'filled');
        
            else
        
                app.SandboxEfieldPointsHandle.XData = X(:);
                app.SandboxEfieldPointsHandle.YData = Y(:);
        
            end
        
            % =====================================================
            % Update Sandbox Network From Controls
            % =====================================================
            updateSandboxNetworkFromControls(app);
        
            % =====================================================
            % Build Simulation Network
            % =====================================================
            [Ssim,Lsim,Tsim,latq,lonq] = ...
                buildSandboxSimulationNetwork(app, app.SandboxS, app.SandboxL, app.SandboxT);
        
            % =====================================================
            % Sandbox Simulation
            % =====================================================
            app.sandBoxMode = 1;
            app.theta = 0:app.SandboxAngleStep:app.SandboxAngle;
        
            % --- Run calculation
            [~,~,~,app.GIC_sandBox,~,~,~,~] = ...
                calc_gic_main( ...
                app,...
                Ssim,...
                Lsim,...
                Tsim,...
                app.EfieldValueEditField.Value,...
                [],...
                latq,...
                lonq,...
                [],...
                Lsim,...
                Tsim);

            % =====================================================
            %  Plot: provide both current and previous (old) results
            % =====================================================
            plotSandboxResults( ...
                app,...
                app.theta,...
                app.GIC_sandBox.Subs,...
                app.GIC_sandBox.Lines,...
                app.GIC_sandBox.iInduced,...
                app.GIC_sandBox_Old.Lines,...   
                app.GIC_sandBox_Old.Subs,...    
                app.GIC_sandBox_Old.iInduced);        
            
            % --- After plotting, update previous with current for next run
            app.GIC_sandBox_Old.Subs        = app.GIC_sandBox.Subs;
            app.GIC_sandBox_Old.Lines       = app.GIC_sandBox.Lines;
            app.GIC_sandBox_Old.iInduced    = app.GIC_sandBox.iInduced;
            app.OldSandboxL             = app.SandboxL;
            
            app.ShowAllCheckBox.Enable = 'on';
            % =====================================================
            % Status Message
            % =====================================================
            msg = sprintf('%d E-field angles',length(app.theta)); 
            app.StatusTextArea.Value = ...
                [app.StatusTextArea.Value; {msg}];
        
            % =====================================================
            % Finished
            % =====================================================
            app.sandBoxMode = 0;
        
            app.SBLamp.Color = [0 1 0];
        
        catch ME
        
            app.sandBoxMode = 0;
        
            app.SBLamp.Color = [1 0 0];
        
            app.StatusTextArea.Value = ...
                [app.StatusTextArea.Value;
                {['❌ Sandbox Error: ' ME.message]}];
        
        end

        end

        % Button pushed function: ResetSubsPositionButton
        function ResetSubsPositionButtonPushed(app, event)
            resetSandbox(app);
        end

        % Value changed function: ShowAllCheckBox
        function ShowAllCheckBoxValueChanged(app, event)
            % --- Plot: provide both current and previous (old) results
            plotSandboxResults( ...
                app,...
                app.theta,...
                app.GIC_sandBox.Subs,...
                app.GIC_sandBox.Lines,...
                app.GIC_sandBox.iInduced,...
                app.GIC_sandBox_Old.Lines,...   
                app.GIC_sandBox_Old.Subs,...    
                app.GIC_sandBox_Old.iInduced);    
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.OptionsTab = uitab(app.TabGroup);
            app.OptionsTab.Title = 'Options';
            app.ControlPannelPanel = uipanel(app.OptionsTab);
            app.ControlPannelPanel.Title = 'Control Pannel';
            app.ImpedanceDataPanel = uipanel(app.ControlPannelPanel);
            app.ImpedanceDataPanel.Title = 'Impedance Data';
            app.ImpedanceFileTextArea = uitextarea(app.ImpedanceDataPanel);
            app.SelectLineImpedanceFileButton = uibutton(app.ImpedanceDataPanel, 'push');
            app.SelectLineImpedanceFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImpedanceFileButtonPushed, true);
            app.SelectLineImpedanceFileButton.Text = 'Select Line Impedance File';
            app.LineImpedanceFileTextArea = uitextarea(app.ImpedanceDataPanel);
            app.SelectSurfaceImpedanceFileButton = uibutton(app.ImpedanceDataPanel, 'push');
            app.SelectSurfaceImpedanceFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSurfaceImpedanceFileButtonPushed, true);
            app.SelectSurfaceImpedanceFileButton.Text = 'Select Surface Impedance File';
            app.LoadFilesButton = uibutton(app.ControlPannelPanel, 'push');
            app.LoadFilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFilesButtonPushed, true);
            app.LoadFilesButton.Text = 'Load Files';
            app.FrequencyDropDownLabel = uilabel(app.ControlPannelPanel);
            app.FrequencyDropDownLabel.Text = 'Frequency';
            app.FrequencyDropDown = uidropdown(app.ControlPannelPanel);
            app.FrequencyDropDown.Items = {'1 Hz', '1 min'};
            app.FrequencyDropDown.ItemsData = [1 2];
            app.FrequencyDropDown.ValueChangedFcn = createCallbackFcn(app, @FrequencyDropDownValueChanged, true);
            app.FrequencyDropDown.Value = 1;
            app.UniformityCheckBox = uicheckbox(app.ControlPannelPanel);
            app.UniformityCheckBox.ValueChangedFcn = createCallbackFcn(app, @UniformityCheckBoxValueChanged, true);
            app.UniformityCheckBox.Text = 'Uniformity';
            app.Hyperlink = uihyperlink(app.ControlPannelPanel);
            app.Hyperlink.URL = 'https://www.carisma.ca/carisma-data-repository';
            app.Hyperlink.Text = 'Download CARISMA Data';
            app.ZTypeDropDownLabel = uilabel(app.ControlPannelPanel);
            app.ZTypeDropDownLabel.Text = 'ZType';
            app.ZTypeDropDown = uidropdown(app.ControlPannelPanel);
            app.ZTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ZTypeDropDownValueChanged, true);
            app.SelectMagneticFileButton = uibutton(app.ControlPannelPanel, 'push');
            app.SelectMagneticFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectMagneticFileButtonPushed, true);
            app.SelectMagneticFileButton.Text = 'Select Magnetic File';
            app.MagneticFileTextArea = uitextarea(app.ControlPannelPanel);
            app.NetworkFileTextArea = uitextarea(app.ControlPannelPanel);
            app.LoadNetworkButton = uibutton(app.ControlPannelPanel, 'push');
            app.LoadNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNetworkButtonPushed, true);
            app.LoadNetworkButton.Text = 'Load Network';
            app.TimePanel = uipanel(app.OptionsTab);
            app.TimePanel.Title = 'Time';
            app.StartDatePickerLabel = uilabel(app.TimePanel);
            app.StartDatePickerLabel.Text = 'StartDatePicker';
            app.StartDatePicker = uidatepicker(app.TimePanel);
            app.StartDatePicker.DisplayFormat = 'yyyy-MM-dd';
            app.EndDatePickerLabel = uilabel(app.TimePanel);
            app.EndDatePickerLabel.Text = 'EndDatePicker';
            app.EndDatePicker = uidatepicker(app.TimePanel);
            app.EndDatePicker.DisplayFormat = 'yyyy-MM-dd';
            app.ValidateButton = uibutton(app.TimePanel, 'push');
            app.ValidateButton.ButtonPushedFcn = createCallbackFcn(app, @ValidateButtonPushed, true);
            app.ValidateButton.Text = 'Validate';
            app.StartTimeDropDownLabel = uilabel(app.TimePanel);
            app.StartTimeDropDownLabel.Text = 'StartTime';
            app.StartTimeDropdown = uidropdown(app.TimePanel);
            app.EndTimeDropDownLabel = uilabel(app.TimePanel);
            app.EndTimeDropDownLabel.Text = 'EndTime';
            app.EndTimeDropdown = uidropdown(app.TimePanel);
            app.UsesingletimeCheckBox = uicheckbox(app.TimePanel);
            app.UsesingletimeCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsesingletimeCheckBoxValueChanged, true);
            app.UsesingletimeCheckBox.Text = 'Use single time';
            app.ActionPanel = uipanel(app.OptionsTab);
            app.ActionPanel.Title = 'Action';
            app.RunGICSimulationButton = uibutton(app.ActionPanel, 'push');
            app.RunGICSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunGICSimulationButtonPushed, true);
            app.RunGICSimulationButton.Text = 'Run GIC Simulation';
            app.RunGICOverviewButton = uibutton(app.ActionPanel, 'push');
            app.RunGICOverviewButton.ButtonPushedFcn = createCallbackFcn(app, @RunGICOverviewButtonPushed, true);
            app.RunGICOverviewButton.Text = 'Run GIC Overview';
            app.StatusLamp = uilamp(app.ActionPanel);
            app.StatusLamp_2 = uilamp(app.ActionPanel);
            app.SelectMapPlotDropDownLabel = uilabel(app.ActionPanel);
            app.SelectMapPlotDropDownLabel.Text = 'Select Map Plot';
            app.GICMapModeDropdown = uidropdown(app.ActionPanel);
            app.GICMapModeDropdown.Items = {'Snapshot', 'Max GIC', 'GIC Change'};
            app.GICMapModeDropdown.Value = 'Snapshot';
            app.PlotButton = uibutton(app.ActionPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.StatusLamp_3 = uilamp(app.ActionPanel);
            app.GICSimulatorLabel = uilabel(app.OptionsTab);
            app.GICSimulatorLabel.Text = 'GIC Simulator';
            app.StatusPanel = uipanel(app.OptionsTab);
            app.StatusPanel.Title = 'Status';
            app.StatusTextArea = uitextarea(app.StatusPanel);
            app.StatusTextArea.Editable = 'off';
            app.CalculateButton = uibutton(app.OptionsTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.FigExportSetingsPanel = uipanel(app.OptionsTab);
            app.FigExportSetingsPanel.Title = 'Fig Export Setings';
            app.GraphDropDownLabel = uilabel(app.FigExportSetingsPanel);
            app.GraphDropDownLabel.Text = 'Graph';
            app.GraphDropdown = uidropdown(app.FigExportSetingsPanel);
            app.GraphDropdown.ValueChangedFcn = createCallbackFcn(app, @GraphDropdownValueChanged, true);
            app.FormatDropDownLabel = uilabel(app.FigExportSetingsPanel);
            app.FormatDropDownLabel.Text = 'Format';
            app.FormatDropdown = uidropdown(app.FigExportSetingsPanel);
            app.DPIEditFieldLabel = uilabel(app.FigExportSetingsPanel);
            app.DPIEditFieldLabel.Text = 'DPI';
            app.ResolutionField = uieditfield(app.FigExportSetingsPanel, 'numeric');
            app.BackgroundDropDownLabel = uilabel(app.FigExportSetingsPanel);
            app.BackgroundDropDownLabel.Text = 'Background';
            app.BackgroundDropdown = uidropdown(app.FigExportSetingsPanel);
            app.SaveGraphButton = uibutton(app.FigExportSetingsPanel, 'push');
            app.SaveGraphButton.ButtonPushedFcn = createCallbackFcn(app, @SaveGraphButtonPushed, true);
            app.SaveGraphButton.Text = 'Save Graph';
            app.FontEditField = uieditfield(app.FigExportSetingsPanel, 'numeric');
            app.FontEditFieldLabel = uilabel(app.FigExportSetingsPanel);
            app.FontEditFieldLabel.Text = 'Font';
            app.SizeDropDownLabel = uilabel(app.FigExportSetingsPanel);
            app.SizeDropDownLabel.Text = 'Size';
            app.sizeRatioDropDown = uidropdown(app.FigExportSetingsPanel);
            app.LoadEButton = uibutton(app.OptionsTab, 'push');
            app.LoadEButton.ButtonPushedFcn = createCallbackFcn(app, @LoadEButtonPushed, true);
            app.LoadEButton.Text = 'Load E';
            app.eFileTextArea = uitextarea(app.OptionsTab);
            app.ETypeDropDownLabel = uilabel(app.OptionsTab);
            app.ETypeDropDownLabel.Text = 'E Type';
            app.ETypeDropDown = uidropdown(app.OptionsTab);
            app.ETypeDropDown.Items = {'Surface', 'Line'};
            app.ETypeDropDown.Value = 'Surface';
            app.ExportEFieldButton = uibutton(app.OptionsTab, 'push');
            app.ExportEFieldButton.ButtonPushedFcn = createCallbackFcn(app, @ExportEFieldButtonPushed, true);
            app.ExportEFieldButton.Text = 'Export E-Field';
            app.MagneticVisualsTab = uitab(app.TabGroup);
            app.MagneticVisualsTab.SizeChangedFcn = createCallbackFcn(app, @MagneticVisualsTabSizeChanged, true);
            app.MagneticVisualsTab.Title = 'Magnetic Visuals';
            app.MagneticBPlotsPanel = uipanel(app.MagneticVisualsTab);
            app.MagneticBPlotsPanel.Title = 'Magnetics (b) plots';
            app.ControlsPanel = uipanel(app.MagneticVisualsTab);
            app.ControlsPanel.Title = 'Controls';
            app.MapPanel = uipanel(app.MagneticVisualsTab);
            app.MapPanel.Title = 'Map';
            app.SiteListBoxLabel = uilabel(app.MagneticVisualsTab);
            app.SiteListBoxLabel.Text = 'Site';
            app.SiteListBox = uilistbox(app.MagneticVisualsTab);
            app.SiteListBox.Multiselect = 'on';
            app.SiteListBox.ValueChangedFcn = createCallbackFcn(app, @SiteListBoxValueChanged, true);
            app.SiteListBox.Value = {'Item 1'};
            app.GICMitigationStrategyTab = uitab(app.TabGroup);
            app.GICMitigationStrategyTab.Title = 'GIC Mitigation Strategy';
            app.MitigationSettingsPanel = uipanel(app.GICMitigationStrategyTab);
            app.MitigationSettingsPanel.Title = 'Mitigation Settings';
            app.TurnoffallHighvoltagelinesindividuallyCheckBox = uicheckbox(app.MitigationSettingsPanel);
            app.TurnoffallHighvoltagelinesindividuallyCheckBox.Text = 'Turn off all High voltage lines individually';
            app.RunothermassindividualmitigationsCheckBox = uicheckbox(app.MitigationSettingsPanel);
            app.RunothermassindividualmitigationsCheckBox.Text = 'Run other mass individual mitigations';
            app.MitigationModeDropDown = uidropdown(app.MitigationSettingsPanel);
            app.MitigationModeDropDown.Items = {'Mode 1: All Lines and Neutral Blockers', 'Mode 2: Neutral blockers (all wye windings)', 'Mode 3: Any line', 'Mode 4: Parallel Lines'};
            app.MitigationModeDropDown.Value = 'Mode 1: All Lines and Neutral Blockers';
            app.SetupPanel = uipanel(app.GICMitigationStrategyTab);
            app.SetupPanel.Title = 'Setup';
            app.MinchangeEditFieldLabel = uilabel(app.SetupPanel);
            app.MinchangeEditFieldLabel.Text = 'Min % change';
            app.ThresholdPctEdit = uieditfield(app.SetupPanel, 'numeric');
            app.ThresholdPctEdit.Limits = [0 100];
            app.ThresholdPctEdit.Value = 30;
            app.MinAEditFieldLabel = uilabel(app.SetupPanel);
            app.MinAEditFieldLabel.Text = 'Min |Δ| (A)';
            app.AbsThresholdAEdit = uieditfield(app.SetupPanel, 'numeric');
            app.AbsThresholdAEdit.Limits = [0 Inf];
            app.AbsThresholdAEdit.Value = 3;
            app.UpdateBtn = uibutton(app.SetupPanel, 'push');
            app.UpdateBtn.ButtonPushedFcn = createCallbackFcn(app, @UpdateBtnButtonPushed, true);
            app.UpdateBtn.Text = 'Update spreadsheet';
            app.PlotBtn = uibutton(app.SetupPanel, 'push');
            app.PlotBtn.ButtonPushedFcn = createCallbackFcn(app, @PlotBtnButtonPushed, true);
            app.PlotBtn.Text = 'Plot graph';
            app.ClearBtn = uibutton(app.SetupPanel, 'push');
            app.ClearBtn.Text = 'Clear';
            app.ExportBtn = uibutton(app.SetupPanel, 'push');
            app.ExportBtn.Text = 'Export';
            app.UpdateLamp = uilamp(app.SetupPanel);
            app.PlotLamp = uilamp(app.SetupPanel);
            app.SpreadsheetResultsPanel = uipanel(app.GICMitigationStrategyTab);
            app.SpreadsheetResultsPanel.Title = 'Spreadsheet Results';
            app.RandomizedmitigationsettingPanel = uipanel(app.GICMitigationStrategyTab);
            app.RandomizedmitigationsettingPanel.Title = 'Randomizedmitigation setting';
            app.HighvoltagelinesCheckBox = uicheckbox(app.RandomizedmitigationsettingPanel);
            app.HighvoltagelinesCheckBox.Text = 'High voltage lines';
            app.ParallellinesCheckBox = uicheckbox(app.RandomizedmitigationsettingPanel);
            app.ParallellinesCheckBox.Text = 'Parallel lines ';
            app.NeutralblockersCheckBox = uicheckbox(app.RandomizedmitigationsettingPanel);
            app.NeutralblockersCheckBox.Text = 'Neutral blockers';
            app.AlllinesCheckBox = uicheckbox(app.RandomizedmitigationsettingPanel);
            app.AlllinesCheckBox.Text = 'All lines';
            app.NumofSimulationEditFieldLabel = uilabel(app.RandomizedmitigationsettingPanel);
            app.NumofSimulationEditFieldLabel.Text = 'Num of Simulation';
            app.NumofSimulationEditField = uieditfield(app.RandomizedmitigationsettingPanel, 'numeric');
            app.MinMitigationsimEditFieldLabel = uilabel(app.RandomizedmitigationsettingPanel);
            app.MinMitigationsimEditFieldLabel.Text = 'Min Mitigation/sim';
            app.MinMitigationsimEditField = uieditfield(app.RandomizedmitigationsettingPanel, 'numeric');
            app.MaxMitigationsimEditFieldLabel = uilabel(app.RandomizedmitigationsettingPanel);
            app.MaxMitigationsimEditFieldLabel.Text = 'Max Mitigation/sim';
            app.MaxMitigationsimEditField = uieditfield(app.RandomizedmitigationsettingPanel, 'numeric');
            app.ResulttypeDropDownLabel = uilabel(app.RandomizedmitigationsettingPanel);
            app.ResulttypeDropDownLabel.Text = 'Result type ';
            app.ResulttypeDropDown = uidropdown(app.RandomizedmitigationsettingPanel);
            app.ResulttypeDropDown.Items = {'Max Trans GIC', 'Change in Total GIC'};
            app.ResulttypeDropDown.Value = 'Max Trans GIC';
            app.TargetValueEditFieldLabel = uilabel(app.RandomizedmitigationsettingPanel);
            app.TargetValueEditFieldLabel.Text = 'Target Value';
            app.TargetValueEditField = uieditfield(app.RandomizedmitigationsettingPanel, 'numeric');
            app.RunRandomMitigationButton = uibutton(app.RandomizedmitigationsettingPanel, 'push');
            app.RunRandomMitigationButton.ButtonPushedFcn = createCallbackFcn(app, @RunRandomMitigationButtonPushed, true);
            app.RunRandomMitigationButton.Text = 'Run Random Mitigation';
            app.SandboxtopologyeditorTab = uitab(app.TabGroup);
            app.SandboxtopologyeditorTab.Title = 'Sandbox topology editor';
            app.SandboxAxes = uiaxes(app.SandboxtopologyeditorTab);
            xlabel(app.SandboxAxes, 'X')
            ylabel(app.SandboxAxes, 'Y')
            zlabel(app.SandboxAxes, 'Z')
            app.ToolboxPanel = uipanel(app.SandboxtopologyeditorTab);
            app.ToolboxPanel.Title = 'Toolbox';
            app.TabGroup3 = uitabgroup(app.ToolboxPanel);
            app.S1Tab = uitab(app.TabGroup3);
            app.S1Tab.Title = 'S1';
            app.S2Tab = uitab(app.TabGroup3);
            app.S2Tab.Title = 'S2';
            app.S3Tab = uitab(app.TabGroup3);
            app.S3Tab.Title = 'S3';
            app.S4Tab = uitab(app.TabGroup3);
            app.S4Tab.Title = 'S4';
            app.S5Tab = uitab(app.TabGroup3);
            app.S5Tab.Title = 'S5';
            app.S6Tab = uitab(app.TabGroup3);
            app.S6Tab.Title = 'S6';
            app.TabGroup4 = uitabgroup(app.ToolboxPanel);
            app.L1Tab = uitab(app.TabGroup4);
            app.L1Tab.Title = 'L1';
            app.L2Tab = uitab(app.TabGroup4);
            app.L2Tab.Title = 'L2';
            app.L3Tab = uitab(app.TabGroup4);
            app.L3Tab.Title = 'L3';
            app.L4Tab = uitab(app.TabGroup4);
            app.L4Tab.Title = 'L4';
            app.L5Tab = uitab(app.TabGroup4);
            app.L5Tab.Title = 'L5';
            app.Tab = uitab(app.TabGroup4);
            app.Tab.Title = 'Tab';
            app.Tab2 = uitab(app.TabGroup4);
            app.Tab2.Title = 'Tab2';
            app.ofE_fieldpointsnxnnEditFieldLabel = uilabel(app.ToolboxPanel);
            app.ofE_fieldpointsnxnnEditFieldLabel.Text = '# of E_field points(nxn) n:';
            app.NumberofE_fieldpointsEditField = uieditfield(app.ToolboxPanel, 'numeric');
            app.EfieldValueEditFieldLabel = uilabel(app.ToolboxPanel);
            app.EfieldValueEditFieldLabel.Text = 'E-field Value';
            app.EfieldValueEditField = uieditfield(app.ToolboxPanel, 'numeric');
            app.RunSimulationButton = uibutton(app.ToolboxPanel, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.SBLamp = uilamp(app.ToolboxPanel);
            app.ShowPreviousResultCheckBox = uicheckbox(app.ToolboxPanel);
            app.ShowPreviousResultCheckBox.Text = 'Show Previous Result';
            app.TabGroup2 = uitabgroup(app.SandboxtopologyeditorTab);
            app.Gic_subvsEfieldOrientaionTab = uitab(app.TabGroup2);
            app.Gic_subvsEfieldOrientaionTab.Title = 'Gic_sub vs Efield Orientaion';
            app.SBUIAxes = uiaxes(app.Gic_subvsEfieldOrientaionTab);
            title(app.SBUIAxes, 'Title')
            xlabel(app.SBUIAxes, 'X')
            ylabel(app.SBUIAxes, 'Y')
            zlabel(app.SBUIAxes, 'Z')
            app.ShowAllCheckBox = uicheckbox(app.Gic_subvsEfieldOrientaionTab);
            app.ShowAllCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowAllCheckBoxValueChanged, true);
            app.ShowAllCheckBox.Text = 'Show All';
            app.Gic_linevsEFieldOrientationTab = uitab(app.TabGroup2);
            app.Gic_linevsEFieldOrientationTab.Title = 'Gic_line vs EField Orientation';
            app.SBUIAxes2 = uiaxes(app.Gic_linevsEFieldOrientationTab);
            title(app.SBUIAxes2, 'Title')
            xlabel(app.SBUIAxes2, 'X')
            ylabel(app.SBUIAxes2, 'Y')
            zlabel(app.SBUIAxes2, 'Z')
            app.EmfvsEfieldOrientation = uitab(app.TabGroup2);
            app.EmfvsEfieldOrientation.Title = 'Induced_I vs Efield Orientation';
            app.SBUIAxes3 = uiaxes(app.EmfvsEfieldOrientation);
            title(app.SBUIAxes3, 'Title')
            xlabel(app.SBUIAxes3, 'X')
            ylabel(app.SBUIAxes3, 'Y')
            zlabel(app.SBUIAxes3, 'Z')
            app.ResetSubsPositionButton = uibutton(app.SandboxtopologyeditorTab, 'push');
            app.ResetSubsPositionButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSubsPositionButtonPushed, true);
            app.ResetSubsPositionButton.Text = 'Reset Subs Position';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GIC_simulation_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end